classdef CommunicationLab < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ConnectButton              matlab.ui.control.Button
        DisconnectButton           matlab.ui.control.Button
        UpdateButton               matlab.ui.control.Button
        PotReadingLabel            matlab.ui.control.Label
        RGBLEDDropDownLabel        matlab.ui.control.Label
        RGBLEDDropDown             matlab.ui.control.DropDown
        PhotoReadingLabel          matlab.ui.control.Label
        PhotocellReadingLabel      matlab.ui.control.Label
        PotentiometerReadingLabel  matlab.ui.control.Label
        RedLEDButtonGroup          matlab.ui.container.ButtonGroup
        OffButton                  matlab.ui.control.ToggleButton
        OnButton                   matlab.ui.control.ToggleButton
        YellowLEDButtonGroup       matlab.ui.container.ButtonGroup
        OffButton_2                matlab.ui.control.ToggleButton
        OnButton_2                 matlab.ui.control.ToggleButton
        GreenLEDButtonGroup        matlab.ui.container.ButtonGroup
        OffButton_3                matlab.ui.control.ToggleButton
        OnButton_3                 matlab.ui.control.ToggleButton
        BlueLEDButtonGroup         matlab.ui.container.ButtonGroup
        OffButton_4                matlab.ui.control.ToggleButton
        PushbuttoncountersLabel    matlab.ui.control.Label
        RedCount                   matlab.ui.control.Label
        YellowCount                matlab.ui.control.Label
        GreenCount                 matlab.ui.control.Label
        BlueCount                  matlab.ui.control.Label
        OnButton_4                 matlab.ui.control.ToggleButton
    end

    
    properties (Access = private)
        s
        redCounter = 0
        yellowCounter = 0
        greenCounter = 0
        blueCounter = 0
    end
    
    methods (Access = private)
        function connect(app)
            open_ports = instrfind('Type', 'serial', 'Statuts', 'open')
            if ~isempty(open_ports)
                fclose(open_ports);
            end
            delete(instrfind);
            app.s = serial("COM11", "BaudRate", 9600, "Terminator", 10,...
                "BytesAvailableFcnMode", "terminator","BytesAvailableFcn", @app.bytesRecieved)
            fopen(app.s);
            fprintf("\nConnected!\n");
        end
        function disconnect(app)
            fclose(app.s);
            delete(instrfind);
            fprintf("\nDisconnected!\n");
        end
        
        
        function bytesRecieved(app, serialObj, eventData)
            message = fscanf(serialObj);
            message = strtrim(message);
            fprintf("Recieved: %s\n", message);
            % Complete message match area...
            if strcmpi(message, "PRESSED RED")
                % TODO: Increment a RED counter!
            end
            
            % Partial match area
            messageArray = split(message, " ");
            if strcmpi(messageArray(1), "POT")
                app.PotReadingLabel.Text = messageArray(2);                
            end
            if strcmpi(messageArray(1), "PHOTO")
                app.PhotoReadingLabel.Text = messageArray(2);                
            end
            if strcmpi(messageArray(1), "RBUT")
                app.redCounter = app.redCounter + 1;
                app.RedCount.Text = sprintf("%2.0f",app.redCounter);
            end
            if strcmpi(messageArray(1), "YBUT")
                app.yellowCounter = app.yellowCounter + 1;
                app.YellowCount.Text = sprintf("%2.0f",app.yellowCounter);            
            end
            if strcmpi(messageArray(1), "GBUT")
                app.greenCounter = app.greenCounter + 1;
                app.GreenCount.Text = sprintf("%2.0f",app.greenCounter);             
            end
            if strcmpi(messageArray(1), "BBUT")
                app.blueCounter = app.blueCounter + 1;
                app.BlueCount.Text = sprintf("%2.0f",app.blueCounter);           
            end
                
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.connect()
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            app.connect()
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            app.disconnect()
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.disconnect()
            delete(app)
            
        end

        % Callback function
        function LEDONButtonPushed(app, event)
            fprintf(app.s, "ON");
        end

        % Callback function
        function LEDOFFButtonPushed(app, event)
            fprintf(app.s, "OFF");
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            fprintf(app.s, "ADC");
        end

        % Selection changed function: RedLEDButtonGroup
        function RedLEDButtonGroupSelectionChanged(app, event)
            selectedButton = app.RedLEDButtonGroup.SelectedObject;
            if (selectedButton.Text == "On")
                fprintf(app.s, "ROn");
            else 
                fprintf(app.s,"ROff");
            end
        end

        % Selection changed function: YellowLEDButtonGroup
        function YellowLEDButtonGroupSelectionChanged(app, event)
            selectedButton = app.YellowLEDButtonGroup.SelectedObject;
            if (selectedButton.Text == "On")
                fprintf(app.s, "YOn");
            else  
                fprintf(app.s,"YOff");
            end
        end

        % Selection changed function: GreenLEDButtonGroup
        function GreenLEDButtonGroupSelectionChanged(app, event)
            selectedButton = app.GreenLEDButtonGroup.SelectedObject;
            if (selectedButton.Text == "On")
                fprintf(app.s, "GOn");
            else  
                fprintf(app.s,"GOff");
            end
        end

        % Selection changed function: BlueLEDButtonGroup
        function BlueLEDButtonGroupSelectionChanged(app, event)
            selectedButton = app.BlueLEDButtonGroup.SelectedObject;
            if (selectedButton.Text == "On")
                fprintf(app.s, "BOn");
            else  
                fprintf(app.s,"BOff");
            end
        end

        % Value changed function: RGBLEDDropDown
        function RGBLEDDropDownValueChanged(app, event)
            value = app.RGBLEDDropDown.Value;
            fprintf(app.s, value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ConnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.DisconnectButton = uibutton(app.UIFigure, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Text = 'Disconnect';
            app.UpdateButton = uibutton(app.UIFigure, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.PotReadingLabel = uilabel(app.UIFigure);
            app.PotReadingLabel.Text = '1023';
            app.RGBLEDDropDownLabel = uilabel(app.UIFigure);
            app.RGBLEDDropDownLabel.Text = 'RGB LED';
            app.RGBLEDDropDown = uidropdown(app.UIFigure);
            app.RGBLEDDropDown.Items = {'Off', 'Blue', 'Green', 'Cyan', 'Red', 'Purple', 'Yellow', 'White'};
            app.RGBLEDDropDown.ItemsData = {'Off', 'Blue', 'Green', 'Cyan', 'Red', 'Purple', 'Yellow', 'White'};
            app.RGBLEDDropDown.ValueChangedFcn = createCallbackFcn(app, @RGBLEDDropDownValueChanged, true);
            app.RGBLEDDropDown.Value = 'Off';
            app.PhotoReadingLabel = uilabel(app.UIFigure);
            app.PhotoReadingLabel.Text = '1023';
            app.PhotocellReadingLabel = uilabel(app.UIFigure);
            app.PhotocellReadingLabel.Text = 'Photocell Reading';
            app.PotentiometerReadingLabel = uilabel(app.UIFigure);
            app.PotentiometerReadingLabel.Text = 'Potentiometer Reading';
            app.RedLEDButtonGroup = uibuttongroup(app.UIFigure);
            app.RedLEDButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @RedLEDButtonGroupSelectionChanged, true);
            app.RedLEDButtonGroup.Title = 'Red LED';
            app.OffButton = uitogglebutton(app.RedLEDButtonGroup);
            app.OffButton.Text = 'Off';
            app.OffButton.Value = true;
            app.OnButton = uitogglebutton(app.RedLEDButtonGroup);
            app.OnButton.Text = 'On';
            app.YellowLEDButtonGroup = uibuttongroup(app.UIFigure);
            app.YellowLEDButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @YellowLEDButtonGroupSelectionChanged, true);
            app.YellowLEDButtonGroup.Title = 'Yellow LED';
            app.OffButton_2 = uitogglebutton(app.YellowLEDButtonGroup);
            app.OffButton_2.Text = 'Off';
            app.OffButton_2.Value = true;
            app.OnButton_2 = uitogglebutton(app.YellowLEDButtonGroup);
            app.OnButton_2.Text = 'On';
            app.GreenLEDButtonGroup = uibuttongroup(app.UIFigure);
            app.GreenLEDButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @GreenLEDButtonGroupSelectionChanged, true);
            app.GreenLEDButtonGroup.Title = 'Green LED';
            app.OffButton_3 = uitogglebutton(app.GreenLEDButtonGroup);
            app.OffButton_3.Text = 'Off';
            app.OffButton_3.Value = true;
            app.OnButton_3 = uitogglebutton(app.GreenLEDButtonGroup);
            app.OnButton_3.Text = 'On';
            app.BlueLEDButtonGroup = uibuttongroup(app.UIFigure);
            app.BlueLEDButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @BlueLEDButtonGroupSelectionChanged, true);
            app.BlueLEDButtonGroup.Title = 'Blue LED';
            app.OffButton_4 = uitogglebutton(app.BlueLEDButtonGroup);
            app.OffButton_4.Text = 'Off';
            app.OffButton_4.Value = true;
            app.OnButton_4 = uitogglebutton(app.BlueLEDButtonGroup);
            app.OnButton_4.Text = 'On';
            app.PushbuttoncountersLabel = uilabel(app.UIFigure);
            app.PushbuttoncountersLabel.Text = 'Pushbutton counters';
            app.RedCount = uilabel(app.UIFigure);
            app.RedCount.Text = '0';
            app.YellowCount = uilabel(app.UIFigure);
            app.YellowCount.Text = '0';
            app.GreenCount = uilabel(app.UIFigure);
            app.GreenCount.Text = '0';
            app.BlueCount = uilabel(app.UIFigure);
            app.BlueCount.Text = '0';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CommunicationLab

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end