classdef ConnectFourGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        ConnectButton     matlab.ui.control.Button
        DisconnectButton  matlab.ui.control.Button
        ResetButton       matlab.ui.control.Button
        ConnectFourPanel  matlab.ui.container.Panel
        Lamp11            matlab.ui.control.Lamp
        Lamp12            matlab.ui.control.Lamp
        Lamp13            matlab.ui.control.Lamp
        Lamp14            matlab.ui.control.Lamp
        Lamp15            matlab.ui.control.Lamp
        Lamp16            matlab.ui.control.Lamp
        Lamp17            matlab.ui.control.Lamp
        Lamp21            matlab.ui.control.Lamp
        Lamp22            matlab.ui.control.Lamp
        Lamp23            matlab.ui.control.Lamp
        Lamp24            matlab.ui.control.Lamp
        Lamp25            matlab.ui.control.Lamp
        Lamp26            matlab.ui.control.Lamp
        Lamp27            matlab.ui.control.Lamp
        Lamp31            matlab.ui.control.Lamp
        Lamp32            matlab.ui.control.Lamp
        Lamp33            matlab.ui.control.Lamp
        Lamp34            matlab.ui.control.Lamp
        Lamp35            matlab.ui.control.Lamp
        Lamp36            matlab.ui.control.Lamp
        Lamp37            matlab.ui.control.Lamp
        Lamp41            matlab.ui.control.Lamp
        Lamp42            matlab.ui.control.Lamp
        Lamp43            matlab.ui.control.Lamp
        Lamp44            matlab.ui.control.Lamp
        Lamp45            matlab.ui.control.Lamp
        Lamp46            matlab.ui.control.Lamp
        Lamp47            matlab.ui.control.Lamp
        Lamp51            matlab.ui.control.Lamp
        Lamp52            matlab.ui.control.Lamp
        Lamp53            matlab.ui.control.Lamp
        Lamp54            matlab.ui.control.Lamp
        Lamp55            matlab.ui.control.Lamp
        Lamp56            matlab.ui.control.Lamp
        Lamp57            matlab.ui.control.Lamp
        Lamp61            matlab.ui.control.Lamp
        Lamp62            matlab.ui.control.Lamp
        Lamp63            matlab.ui.control.Lamp
        Lamp64            matlab.ui.control.Lamp
        Lamp65            matlab.ui.control.Lamp
        Lamp66            matlab.ui.control.Lamp
        Lamp67            matlab.ui.control.Lamp
        Lamp01            matlab.ui.control.Lamp
        Lamp02            matlab.ui.control.Lamp
        Lamp03            matlab.ui.control.Lamp
        Lamp04            matlab.ui.control.Lamp
        Lamp05            matlab.ui.control.Lamp
        Lamp06            matlab.ui.control.Lamp
        Lamp07            matlab.ui.control.Lamp
        TurnLabel         matlab.ui.control.Label
        LeftButton        matlab.ui.control.Button
        RightButton       matlab.ui.control.Button
        InsertButton      matlab.ui.control.Button
    end

    
    properties (Access = public)
        s
        str
        slotMatrix = zeros(6,7)
        previewPos = 1
        turn = 1                        % 1 is RED's turn, 2 is BLUE's turn
        victor = 0
    end
    
    methods (Access = public)
        function connect(app)
            open_ports = instrfind('Type', 'serial', 'Statuts', 'open')
            if ~isempty(open_ports)
                fclose(open_ports);
            end
            delete(instrfind);
            app.s = serial("COM11", "BaudRate", 9600, "Terminator", 10,...
                "BytesAvailableFcnMode", "terminator","BytesAvailableFcn", @app.bytesRecieved);
            fopen(app.s);
            fprintf("\nConnected!\n");
        end
        function disconnect(app)
            fclose(app.s);
            delete(instrfind);
            fprintf("\nDisconnected!\n");
        end
        
        
        function bytesRecieved(app, serialObj, eventData)
            message = fscanf(serialObj);
            message = strtrim(message);
            fprintf("Recieved: %s\n", message);

            messageArray = split(message, " ");
            if strcmpi(messageArray(1), "RBUT")
                app.insertPiece();
            end
            if strcmpi(messageArray(1), "YBUT")
                app.updatePos("LEFT");
            end
            if strcmpi(messageArray(1), "GBUT")
                app.updatePos("RIGHT");
            end
            if strcmpi(messageArray(1), "BBUT")
                app.insertPiece();
            end
        end

        
        function insertPiece(app)
            index = 0;
            for row = 1:6
                if app.slotMatrix(row, app.previewPos) == 0
                    index = row;
                end
            end
            if index ~= 0

                app.slotMatrix(index, app.previewPos) = app.turn;
                app.previewPos = 1;
                app.turn = mod(app.turn, 2) + 1;
            end
            
            app.update();
        end

        
        function resetBoard(app)
            app.slotMatrix = zeros(6,7);
            app.turn = mod(app.turn, 2) + 1;
            app.victor = 0;
            app.previewPos = 1;
            app.update();
        end
        
        function updatePos(app, direction)
            if direction == "LEFT"
                app.previewPos = app.previewPos - 1;
            elseif direction == "RIGHT"
                app.previewPos = app.previewPos + 1;
            end
            app.previewPos = mod(app.previewPos-1, 7) + 1;
            app.update();
        end
        
        
        function updateSlot(app, row, col, color)
            if color == 0
                app.str = sprintf("app.Lamp%1.0f%1.0f.Color = [1, 1, 1];",row, col);   % Clears this slot
            elseif color == 1
                app.str = sprintf("app.Lamp%1.0f%1.0f.Color = [1, 0, 0];",row, col);   % Turns this slot red
            elseif color == 2
                app.str = sprintf("app.Lamp%1.0f%1.0f.Color = [0, 0, 1];",row, col);   % Turns this slot blue
            end
            eval(app.str);
            app.str = "";
        end
        
        function update(app)
            if app.victor ~= 0
                app.resetBoard();
            end
            for col = 1:7
                for row = 1:6
                    app.updateSlot(row,col,app.slotMatrix(row,col));    % 0 is clear, 1 is red, 2 is blue
                    app.winnerCheckHelper(row, col);
                end
                app.updateSlot(0, col, 0);
            end
            app.updateSlot(0, app.previewPos, app.turn);
            if app.victor ~= 0
                if app.victor == 1
                    app.TurnLabel.Text = "Red Wins!";
                    fprintf(app.s, "RVIC");
                elseif app.victor == 2
                    app.TurnLabel.Text = "Blue Wins!";
                    fprintf(app.s, "BVIC");
                end
            elseif app.turn == 1
                app.TurnLabel.Text = "It's Red's Turn";
                fprintf(app.s, "RED");
            elseif app.turn == 2
                app.TurnLabel.Text = "It's Blue's Turn";
                fprintf(app.s, "BLUE");
            end
        end
        
        
        function winnerCheckHelper(app, row, col)
            temp = app.slotMatrix(row, col);
            if (temp == 1) || (temp == 2)
                if row < 4
                    if (temp == app.slotMatrix(row, col)) && (temp == app.slotMatrix(row+1, col)) && (temp == app.slotMatrix(row+2, col)) && (temp == app.slotMatrix(row+3, col))
                        app.victor = temp;
                    end
                    if col < 5
                        if (temp == app.slotMatrix(row, col)) && (temp == app.slotMatrix(row+1, col+1)) && (temp == app.slotMatrix(row+2, col+2)) && (temp == app.slotMatrix(row+3, col+3))
                            app.victor = temp;
                        end
                    end
                end
                if col < 5
                    if (temp == app.slotMatrix(row, col)) && (temp == app.slotMatrix(row, col+1)) && (temp == app.slotMatrix(row, col+2)) && (temp == app.slotMatrix(row, col+3))
                        app.victor = temp;
                    end
                    if row > 3
                        if (temp == app.slotMatrix(row, col)) && (temp == app.slotMatrix(row-1, col+1)) && (temp == app.slotMatrix(row-2, col+2)) && (temp == app.slotMatrix(row-3, col+3))
                            app.victor = temp;
                        end
                    end
                    
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.connect();
            clc
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            app.connect();
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            app.disconnect();
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.disconnect()
            delete(app)
            clc
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.resetBoard();
        end

        % Button pushed function: LeftButton
        function LeftButtonPushed(app, event)
            app.updatePos("LEFT");
        end

        % Button pushed function: RightButton
        function RightButtonPushed(app, event)
            app.updatePos("RIGHT");
        end

        % Button pushed function: InsertButton
        function InsertButtonPushed(app, event)
            app.insertPiece();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ConnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.DisconnectButton = uibutton(app.UIFigure, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Text = 'Disconnect';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ConnectFourPanel = uipanel(app.UIFigure);
            app.ConnectFourPanel.Title = 'ConnectFour';
            app.Lamp01 = uilamp(app.ConnectFourPanel);
            app.Lamp02 = uilamp(app.ConnectFourPanel);
            app.Lamp03 = uilamp(app.ConnectFourPanel);
            app.Lamp04 = uilamp(app.ConnectFourPanel);
            app.Lamp05 = uilamp(app.ConnectFourPanel);
            app.Lamp06 = uilamp(app.ConnectFourPanel);
            app.Lamp07 = uilamp(app.ConnectFourPanel);
            app.Lamp11 = uilamp(app.ConnectFourPanel);
            app.Lamp12 = uilamp(app.ConnectFourPanel);
            app.Lamp13 = uilamp(app.ConnectFourPanel);
            app.Lamp14 = uilamp(app.ConnectFourPanel);
            app.Lamp15 = uilamp(app.ConnectFourPanel);
            app.Lamp16 = uilamp(app.ConnectFourPanel);
            app.Lamp17 = uilamp(app.ConnectFourPanel);
            app.Lamp21 = uilamp(app.ConnectFourPanel);
            app.Lamp22 = uilamp(app.ConnectFourPanel);
            app.Lamp23 = uilamp(app.ConnectFourPanel);
            app.Lamp24 = uilamp(app.ConnectFourPanel);
            app.Lamp25 = uilamp(app.ConnectFourPanel);
            app.Lamp26 = uilamp(app.ConnectFourPanel);
            app.Lamp27 = uilamp(app.ConnectFourPanel);
            app.Lamp31 = uilamp(app.ConnectFourPanel);
            app.Lamp32 = uilamp(app.ConnectFourPanel);
            app.Lamp33 = uilamp(app.ConnectFourPanel);
            app.Lamp34 = uilamp(app.ConnectFourPanel);
            app.Lamp35 = uilamp(app.ConnectFourPanel);
            app.Lamp36 = uilamp(app.ConnectFourPanel);
            app.Lamp37 = uilamp(app.ConnectFourPanel);
            app.Lamp41 = uilamp(app.ConnectFourPanel);
            app.Lamp42 = uilamp(app.ConnectFourPanel);
            app.Lamp43 = uilamp(app.ConnectFourPanel);
            app.Lamp44 = uilamp(app.ConnectFourPanel);
            app.Lamp45 = uilamp(app.ConnectFourPanel);
            app.Lamp46 = uilamp(app.ConnectFourPanel);
            app.Lamp47 = uilamp(app.ConnectFourPanel);
            app.Lamp51 = uilamp(app.ConnectFourPanel);
            app.Lamp52 = uilamp(app.ConnectFourPanel);
            app.Lamp53 = uilamp(app.ConnectFourPanel);
            app.Lamp54 = uilamp(app.ConnectFourPanel);
            app.Lamp55 = uilamp(app.ConnectFourPanel);
            app.Lamp56 = uilamp(app.ConnectFourPanel);
            app.Lamp57 = uilamp(app.ConnectFourPanel);
            app.Lamp61 = uilamp(app.ConnectFourPanel);
            app.Lamp62 = uilamp(app.ConnectFourPanel);
            app.Lamp63 = uilamp(app.ConnectFourPanel);
            app.Lamp64 = uilamp(app.ConnectFourPanel);
            app.Lamp65 = uilamp(app.ConnectFourPanel);
            app.Lamp66 = uilamp(app.ConnectFourPanel);
            app.Lamp67 = uilamp(app.ConnectFourPanel);
            app.TurnLabel = uilabel(app.UIFigure);
            app.TurnLabel.Text = 'Ready To Play?';
            app.LeftButton = uibutton(app.UIFigure, 'push');
            app.LeftButton.ButtonPushedFcn = createCallbackFcn(app, @LeftButtonPushed, true);
            app.LeftButton.Text = 'Left';
            app.RightButton = uibutton(app.UIFigure, 'push');
            app.RightButton.ButtonPushedFcn = createCallbackFcn(app, @RightButtonPushed, true);
            app.RightButton.Text = 'Right';
            app.InsertButton = uibutton(app.UIFigure, 'push');
            app.InsertButton.ButtonPushedFcn = createCallbackFcn(app, @InsertButtonPushed, true);
            app.InsertButton.Text = 'Insert';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ConnectFourGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end